classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ButterworthLabel    matlab.ui.control.Label
        OriginalLabel       matlab.ui.control.Label
        SelectModeLabel     matlab.ui.control.Label
        FilteredImageLabel  matlab.ui.control.Label
        GaussianLabel       matlab.ui.control.Label
        IdealLabel          matlab.ui.control.Label
        NoiseButton         matlab.ui.control.Button
        BrightenButton      matlab.ui.control.Button
        SharpenButton       matlab.ui.control.Button
        SmoothButton        matlab.ui.control.Button
        NewButton           matlab.ui.control.Button
        UploadButton        matlab.ui.control.Button
        FrequencyDomainImageFilteringLabel  matlab.ui.control.Label
        AfterImageAxes_3    matlab.ui.control.UIAxes
        AfterImageAxes_2    matlab.ui.control.UIAxes
        AfterImageAxes_1    matlab.ui.control.UIAxes
        BeforeImageAxes     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        oriImage % Original image, meant to be processed
    end
    
    methods (Access = private)
        function uploadPhoto(app)
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
            end
            ImageFile = imread(fullname);
            imagesc(app.BeforeImageAxes,ImageFile);
            app.oriImage = ImageFile;

            app.NewButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.BeforeImageAxes.Colormap = gray(256);
            axis(app.BeforeImageAxes, 'image');
            app.BeforeImageAxes.Visible = 'off';

            app.AfterImageAxes_1.Visible = 'off';
            app.AfterImageAxes_2.Visible = 'off';
            app.AfterImageAxes_3.Visible = 'off';
            app.AfterImageAxes_1.Colormap = gray(256);
            app.AfterImageAxes_2.Colormap = gray(256);
            app.AfterImageAxes_3.Colormap = gray(256);
            axis(app.AfterImageAxes_1, 'image');
            axis(app.AfterImageAxes_2, 'image');
            axis(app.AfterImageAxes_3, 'image');

            app.NewButton.Enable = 'off';
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            cla(app.BeforeImageAxes);
            cla(app.AfterImageAxes_1);
            cla(app.AfterImageAxes_2);
            cla(app.AfterImageAxes_3);
            app.BeforeImageAxes.Visible = 'off';
            app.AfterImageAxes_1.Visible = 'off';
            app.AfterImageAxes_2.Visible = 'off';
            app.AfterImageAxes_3.Visible = 'off';

            app.OriginalLabel.Visible = 'off';
            app.FilteredImageLabel.Visible = 'off';
            app.IdealLabel.Visible = 'off';
            app.GaussianLabel.Visible = 'off';
            app.ButterworthLabel.Visible = 'off';

            app.NewButton.Enable = 'off';
            app.SmoothButton.Enable = 'on';
            app.SharpenButton.Enable = 'on';
            app.BrightenButton.Enable = 'on';
            app.NoiseButton.Enable = 'on';
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            % Input image
            uploadPhoto(app);

            % Call function
            img_i = ilpf(app.oriImage);
            img_g = glpf(app.oriImage);
            img_b = blpf(app.oriImage);

            % Output image
            imagesc(app.AfterImageAxes_1,img_i);
            imagesc(app.AfterImageAxes_2,img_g);
            imagesc(app.AfterImageAxes_3,img_b);

            app.OriginalLabel.Visible = 'on';
            app.IdealLabel.Visible = 'on';
            app.GaussianLabel.Visible = 'on';
            app.ButterworthLabel.Visible = 'on';

            app.SmoothButton.Enable = 'off';
            app.SharpenButton.Enable = 'off';
            app.BrightenButton.Enable = 'off';
            app.NoiseButton.Enable = 'off';
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            % Input image
            uploadPhoto(app);

            % Call function
            img_i = ihpf(app.oriImage);
            img_g = ghpf(app.oriImage);
            img_b = bhpf(app.oriImage);

            % Output image
            imagesc(app.AfterImageAxes_1,img_i);
            imagesc(app.AfterImageAxes_2,img_g);
            imagesc(app.AfterImageAxes_3,img_b);

            app.OriginalLabel.Visible = 'on';
            app.IdealLabel.Visible = 'on';
            app.GaussianLabel.Visible = 'on';
            app.ButterworthLabel.Visible = 'on';

            app.SmoothButton.Enable = 'off';
            app.SharpenButton.Enable = 'off';
            app.BrightenButton.Enable = 'off';
            app.NoiseButton.Enable = 'off';
        end

        % Button pushed function: BrightenButton
        function BrightenButtonPushed(app, event)
            % Input image
            uploadPhoto(app);

            % Call function
            % ========================= ISI

            % Output image
            imagesc(app.AfterImageAxes_1,img_i);

            app.OriginalLabel.Visible = 'on';
            app.FilteredImageLabel.Visible = 'on';

            app.SmoothButton.Enable = 'off';
            app.SharpenButton.Enable = 'off';
            app.BrightenButton.Enable = 'off';
            app.NoiseButton.Enable = 'off';
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            % Input image -> defined
            % Call function
            % Output image -> defined, processed
            
            app.SmoothButton.Enable = 'off';
            app.SharpenButton.Enable = 'off';
            app.BrightenButton.Enable = 'off';
            app.NoiseButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.BeforeImageAxes = uiaxes(app.UIFigure);
            app.BeforeImageAxes.XTick = [];
            app.BeforeImageAxes.XTickLabel = {'[ ]'};
            app.BeforeImageAxes.YTick = [];
            app.AfterImageAxes_1 = uiaxes(app.UIFigure);
            app.AfterImageAxes_1.XTick = [];
            app.AfterImageAxes_1.XTickLabel = {'[ ]'};
            app.AfterImageAxes_1.YTick = [];
            app.AfterImageAxes_2 = uiaxes(app.UIFigure);
            app.AfterImageAxes_2.XTick = [];
            app.AfterImageAxes_2.XTickLabel = {'[ ]'};
            app.AfterImageAxes_2.YTick = [];
            app.AfterImageAxes_3 = uiaxes(app.UIFigure);
            app.AfterImageAxes_3.XTick = [];
            app.AfterImageAxes_3.XTickLabel = {'[ ]'};
            app.AfterImageAxes_3.YTick = [];
            app.FrequencyDomainImageFilteringLabel = uilabel(app.UIFigure);
            app.FrequencyDomainImageFilteringLabel.Text = 'Frequency Domain Image Filtering';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.Text = 'Upload image';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New image';
            app.SmoothButton = uibutton(app.UIFigure, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.SharpenButton = uibutton(app.UIFigure, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.BrightenButton = uibutton(app.UIFigure, 'push');
            app.BrightenButton.ButtonPushedFcn = createCallbackFcn(app, @BrightenButtonPushed, true);
            app.BrightenButton.Text = 'Brighten';
            app.NoiseButton = uibutton(app.UIFigure, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Remove noise';
            app.IdealLabel = uilabel(app.UIFigure);
            app.IdealLabel.Visible = 'off';
            app.IdealLabel.Text = 'Ideal';
            app.GaussianLabel = uilabel(app.UIFigure);
            app.GaussianLabel.Visible = 'off';
            app.GaussianLabel.Text = 'Gaussian';
            app.FilteredImageLabel = uilabel(app.UIFigure);
            app.FilteredImageLabel.Visible = 'off';
            app.FilteredImageLabel.Text = 'Filtered Image';
            app.SelectModeLabel = uilabel(app.UIFigure);
            app.SelectModeLabel.Text = 'Select Mode';
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Visible = 'off';
            app.OriginalLabel.Text = 'Original';
            app.ButterworthLabel = uilabel(app.UIFigure);
            app.ButterworthLabel.Visible = 'off';
            app.ButterworthLabel.Text = 'Butterworth';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end